classdef MainApp_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FASTLauncherUIFigure  matlab.ui.Figure
        SettingsMenu          matlab.ui.container.Menu
        HelpMenu              matlab.ui.container.Menu
        FASTapperrorsMenu     matlab.ui.container.Menu
        CloseOpenFastMenu     matlab.ui.container.Menu
        SetHydrodinfileMenu   matlab.ui.container.Menu
        OPENFAST_TITLE        matlab.ui.control.Label
        DESCRIPTION           matlab.ui.control.Label
        LAUNCH_BUTTON         matlab.ui.control.Button
        CREDITS               matlab.ui.control.Label
        LOADER                matlab.ui.control.Gauge
        LABELSTATUS           matlab.ui.control.Label
        CHECKBTNPRESSED_FLAG  matlab.ui.control.NumericEditField
        University_logo       matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        flag_1                matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
           
        end

        % Button pushed function: LAUNCH_BUTTON
        function LAUNCH_BUTTONButtonPushed(app, event)
            %         Control whether the user has clicked any mode.
            %             if (app.NEWTURBINE.Value == 0 && app.OPENTURBINE.Value == 0 && app.COMPARISONTURBINE.Value == 0)
            %                 msgbox("You haven't selected any mode! Please select at least one mode.","Error 003","Error");
            %         else
            %%End control
            if(app.flag_1.Value  == 0)
                ans= questdlg("It seems you want to launch FAST without checking the HydroDyn file, are you sure about that?","HydroDyn wasn't checked","Yes","No","No");
            end
            switch(ans)
                case 'Yes'
            app.CHECKBTNPRESSED_FLAG.Value = app.CHECKBTNPRESSED_FLAG.Value + 1;
            if(app.CHECKBTNPRESSED_FLAG.Value == 1)
                app.LAUNCH_BUTTON.Enable = 'Off';
                for i = 1:100
                    app.LOADER.Value = app.LOADER.Value + 1;
                    tmp_value = num2str(app.LOADER.Value);
                    app.LABELSTATUS.Text = strcat(tmp_value,'%');
                    pause(0.01);
                end
                if(app.LOADER.Value == 100)
                    app.LAUNCH_BUTTON.Enable = 'On';
                    app.LAUNCH_BUTTON.BackgroundColor = 'Green';
                    app.LAUNCH_BUTTON.Text = 'Ready!';
                    pause(0.5); %<- short break before the app starts
                    %%THE APPLICATION HERE WILL start
                    %-------------------------------
                    run("C:\Users\secch\Desktop\TESI_Raffaele_Palumbo\App\Other\NewTurbine_Interface.mlapp");
                    app.delete;
                else
                    msgbox({'There was an unknown error while compiling OPENFAST.';'Please report it to the developer, we apologize,thanks.'},'Error 001','Error');
                end
                %             else
                %                 msgbox('Wait for the program to start, you have already pushed the button','Error 002','Error');
                %
                %             end
                
            end
                case'No'
            end
        
        end

        % Menu selected function: CloseOpenFastMenu
        function CloseOpenFastMenuSelected(app, event)
    app.delete;
        end

        % Menu selected function: FASTapperrorsMenu
        function FASTapperrorsMenuSelected(app, event)
    msgbox({"OPENFAST - COMMON ERRORS:";"- Error 001: Very rare error within the code, make sure to download the latest version, it is a running problem.";"- Error 002: The user han't waitied for the program to begin, and clicked somewhere else."},"Error List","Error");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FASTLauncherUIFigure = uifigure('Visible', 'off');
            app.FASTLauncherUIFigure.Name = 'FAST Launcher';
            app.SettingsMenu = uimenu(app.FASTLauncherUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.HelpMenu = uimenu(app.SettingsMenu);
            app.HelpMenu.Text = 'Help';
            app.FASTapperrorsMenu = uimenu(app.HelpMenu);
            app.FASTapperrorsMenu.MenuSelectedFcn = createCallbackFcn(app, @FASTapperrorsMenuSelected, true);
            app.FASTapperrorsMenu.Text = 'FAST app errors';
            app.CloseOpenFastMenu = uimenu(app.SettingsMenu);
            app.CloseOpenFastMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseOpenFastMenuSelected, true);
            app.CloseOpenFastMenu.Separator = 'on';
            app.CloseOpenFastMenu.Text = 'Close OpenFast';
            app.SetHydrodinfileMenu = uimenu(app.FASTLauncherUIFigure);
            app.SetHydrodinfileMenu.Text = 'Set Hydrodin file';
            app.OPENFAST_TITLE = uilabel(app.FASTLauncherUIFigure);
            app.OPENFAST_TITLE.Text = 'OPENFAST';
            app.DESCRIPTION = uilabel(app.FASTLauncherUIFigure);
            app.DESCRIPTION.Text = {'A simple yet efficient way to utilize Openfast: an user-friendly '; 'interface purely developed in MATLAB.'; ''};
            app.LAUNCH_BUTTON = uibutton(app.FASTLauncherUIFigure, 'push');
            app.LAUNCH_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @LAUNCH_BUTTONButtonPushed, true);
            app.LAUNCH_BUTTON.Text = 'Launch';
            app.CREDITS = uilabel(app.FASTLauncherUIFigure);
            app.CREDITS.Text = 'Developed by Raffaele Palumbo';
            app.LOADER = uigauge(app.FASTLauncherUIFigure, 'circular');
            app.LABELSTATUS = uilabel(app.FASTLauncherUIFigure);
            app.LABELSTATUS.Text = 'OFF';
            app.CHECKBTNPRESSED_FLAG = uieditfield(app.FASTLauncherUIFigure, 'numeric');
            app.University_logo = uiimage(app.FASTLauncherUIFigure);
            app.University_logo.ImageSource = 'Logo-Unina.jpg';
            app.Image2 = uiimage(app.FASTLauncherUIFigure);
            app.Image2.ImageSource = 'Rotating Wind turbine.gif';
            app.flag_1 = uieditfield(app.FASTLauncherUIFigure, 'numeric');
            % Show the figure after all components are created
            app.FASTLauncherUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FASTLauncherUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FASTLauncherUIFigure)
        end
    end
end