classdef NewBlade_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SettingsMenu             matlab.ui.container.Menu
        Menu                     matlab.ui.container.Menu
        OPENFASTLabel            matlab.ui.control.Label
        CreatenewbladefileLabel  matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        CommandwindowPanel       matlab.ui.container.Panel
        ChordplotButton          matlab.ui.control.Button
        TwistplotButton          matlab.ui.control.Button
        SaveGeometry             matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        file_cell  % Description
        path_cell
        full_file
        blade
        T
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
             [file,path]=uigetfile('C:\Users\secch\Desktop\TESI_Raffaele_Palumbo\App\FASTFiles(for_testing)\Simply100_DLC1_1_v4.fst');
            if [file,path]==0
            
        else
            
                %Ignore all options
                app.blade=fullfile(path,file);
                varnames = {'RNodes','AeroTwst','DRNodes','Chord','NFoil','PrnElm'};
                vartypes = {'double', 'double', 'double','double','double', 'char' };
                delimiter= {'\t'};
                datastartline = 51;
                extracolrule= 'addvars';
                consdelrule = 'split';
                leadingdelimiter= 'keep';
                
                opts = delimitedTextImportOptions('VariableNames',varnames,...
                    'VariableTypes',vartypes,...
                    'LeadingDelimitersRule',leadingdelimiter,...
                    'Delimiter',delimiter,...
                    'ConsecutiveDelimitersRule',consdelrule,...
                    'DataLines', datastartline,...
                    'ExtraColumnsRule',extracolrule);
                %%
                app.T = readtable("C:\Users\secch\Desktop\TESI_Raffaele_Palumbo\App\FASTFiles(for_testing)\Simply100_DLC1_1_v4.fst");
                app.UITable.Data = app.T;
        end
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.Menu = uimenu(app.SettingsMenu);
            app.Menu.Text = 'Menu';
            app.OPENFASTLabel = uilabel(app.UIFigure);
            app.OPENFASTLabel.Text = 'OPENFAST';
            app.CreatenewbladefileLabel = uilabel(app.UIFigure);
            app.CreatenewbladefileLabel.Text = 'Create new blade file';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'RNodes'; 'AeroTwist'; 'DRNodes'; 'Chord'; 'NFoil'; 'PrElm'};
            app.UITable.RowName = {};
            app.CommandwindowPanel = uipanel(app.UIFigure);
            app.CommandwindowPanel.Title = 'Command window';
            app.ChordplotButton = uibutton(app.CommandwindowPanel, 'push');
            app.ChordplotButton.Text = 'Chord plot';
            app.TwistplotButton = uibutton(app.CommandwindowPanel, 'push');
            app.TwistplotButton.Text = 'Twist plot';
            app.SaveGeometry = uibutton(app.CommandwindowPanel, 'push');
            app.SaveGeometry.Text = 'Save Geometry';
            app.LoadButton = uibutton(app.CommandwindowPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewBlade_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end