classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
        Button    matlab.ui.control.Button
    end

    
    properties (Access = private)
        T % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
                  [file,path]=uigetfile('C:\Users\secch\Desktop\TESI_Raffaele_Palumbo\App\FASTFiles(for_testing)\Blade_and_Tower\Simply100_Fast_Blade_v4.dat');
            if [file,path]==0
            else 
        %%Ignore all options
            varnames = {'BlFract','AeroCent','StrcTwst','BMassDen','FlpStff','EdgStff'};
            vartypes = {'double', 'double', 'double','double','double', 'double' };
            delimiter= {'\t'};
            datastartline = 19;
            extracolrule= 'addvars';
            consdelrule = 'split';
            leadingdelimiter= 'keep';
            opts = delimitedTextImportOptions('VariableNames',varnames,...
                'VariableTypes',vartypes,...
                'LeadingDelimitersRule',leadingdelimiter,...
                'Delimiter',delimiter,...
                'ConsecutiveDelimitersRule',consdelrule,...
                'DataLines', datastartline,...
                'ExtraColumnsRule',extracolrule);
            %%
            %msgbox("success");
            app.T = readtable('C:\Users\secch\Desktop\TESI_Raffaele_Palumbo\App\FASTFiles(for_testing)\Blade_and_Tower\Simply100_Fast_Blade_v4.dat');
            app.UITable.Data = app.T;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end