classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OPENFASTLabel          matlab.ui.control.Label
        DisplaywindowPanel     matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        GridLayout2            matlab.ui.container.GridLayout
        DataTable_Tower        matlab.ui.control.Table
        DataTable_Blade        matlab.ui.control.Table
        CommandwindowPanel     matlab.ui.container.Panel
        TowerPanel             matlab.ui.container.Panel
        TowerLoadPanel         matlab.ui.container.Panel
        LoadTowerFileButton    matlab.ui.control.Button
        ExportTowerFileButton  matlab.ui.control.Button
        TowerFileStatus        matlab.ui.control.Lamp
        FilenotloadedLabel     matlab.ui.control.Label
        FileloadedLabel        matlab.ui.control.Label
        BladePanel             matlab.ui.container.Panel
        TowerLoadPanel_2       matlab.ui.container.Panel
        LoadBladeFile          matlab.ui.control.Button
        ExportBladeFile        matlab.ui.control.Button
        BladeFileStatus        matlab.ui.control.Lamp
        FileNotLoadedBlade     matlab.ui.control.Label
        FileLoadedBlade        matlab.ui.control.Label
    end

    
    properties (Access = private)
        T % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTowerFileButton
        function LoadTowerFileButtonPushed(app, event)
            [file,path]=uigetfile('C:\Users\secch\Desktop\TESI_Raffaele_Palumbo\App\FASTFiles(for_testing)\Blade_and_Tower\Simply100_Fast_Tower_h36_10-8-8-8_v2.dat');
            if [file,path]==0
            else 
        %%Ignore all options
            varnames = {'HtFract','TMassDen','TwFAStif','TwSSStif','TwSSStif','Chord','NFoil','TwSSIner','TwFAcgOf','TwSScgOf'};
            vartypes = {'double', 'double', 'double','double','double', 'double','double','double','double','double' };
            delimiter= {'\t'};
            datastartline = 22;
            extracolrule= 'addvars';
            consdelrule = 'split';
            leadingdelimiter= 'keep';
            opts = delimitedTextImportOptions('VariableNames',varnames,...
                'VariableTypes',vartypes,...
                'LeadingDelimitersRule',leadingdelimiter,...
                'Delimiter',delimiter,...
                'ConsecutiveDelimitersRule',consdelrule,...
                'DataLines', datastartline,...
                'ExtraColumnsRule',extracolrule);
            %%
            %msgbox("success");
            app.T = readtable('C:\Users\secch\Desktop\TESI_Raffaele_Palumbo\App\FASTFiles(for_testing)\Blade_and_Tower\Simply100_Fast_Tower_h36_10-8-8-8_v2.dat',opts);
            app.DataTable_Tower.Data = app.T;
            app.TowerFileStatus.Color = "green";
            app.FilenotloadedLabel.Visible = false;
            app.FileloadedLabel.Visible = true;
            app.ExportTowerFileButton.Enable = true;
            end
        end

        % Callback function
        function LoadBladeFileButtonPushed(app, event)
 
        end

        % Button pushed function: LoadBladeFile
        function LoadBladeFileButtonPushed2(app, event)
      [file,path]=uigetfile('C:\Users\secch\Desktop\TESI_Raffaele_Palumbo\App\FASTFiles(for_testing)\Blade_and_Tower\Simply100_Fast_Blade_v4.dat');
            if [file,path]==0
            else 
        %%Ignore all options
            varnames = {'BlFract','AeroCent','StrcTwst','BMassDen','FlpStff','EdgStff'};
            vartypes = {'double', 'double', 'double','double','double', 'double' };
            delimiter= {'\t'};
            datastartline = 19;
            extracolrule= 'addvars';
            consdelrule = 'split';
            leadingdelimiter= 'keep';
            opts = delimitedTextImportOptions('VariableNames',varnames,...
                'VariableTypes',vartypes,...
                'LeadingDelimitersRule',leadingdelimiter,...
                'Delimiter',delimiter,...
                'ConsecutiveDelimitersRule',consdelrule,...
                'DataLines', datastartline,...
                'ExtraColumnsRule',extracolrule);
            %%
            %msgbox("success");
            app.T = readtable('C:\Users\secch\Desktop\TESI_Raffaele_Palumbo\App\FASTFiles(for_testing)\Blade_and_Tower\Simply100_Fast_Blade_v4.dat',opts);
            app.DataTable_Blade.Data = app.T;
            app.FileNotLoadedBlade.Visible = false;
            app.FileLoadedBlade.Visible = true;
            app.BladeFileStatus.Color = "green";
            app.ExportBladeFile.Enable = true;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OPENFASTLabel = uilabel(app.UIFigure);
            app.OPENFASTLabel.Text = 'OPENFAST';
            app.DisplaywindowPanel = uipanel(app.UIFigure);
            app.DisplaywindowPanel.Title = 'Display window';
            app.GridLayout = uigridlayout(app.DisplaywindowPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DataTable_Tower = uitable(app.GridLayout2);
            app.DataTable_Tower.ColumnName = {'HtFract'; 'TMassDen'; 'TwFAStif'; 'TwSSStif'; 'Chord'; 'NFoil'; 'TwSSStif'; 'TwSSIner'; 'TwFAcgOf'; 'TwSScgOf'};
            app.DataTable_Tower.RowName = {};
            app.DataTable_Blade = uitable(app.GridLayout);
            app.DataTable_Blade.ColumnName = {'BlFract'; 'AeroCent'; 'StrcTwst'; 'BMassDen'; 'FlpStff'; 'EdgStff'};
            app.DataTable_Blade.RowName = {};
            app.DataTable_Blade.ColumnEditable = false;
            app.CommandwindowPanel = uipanel(app.UIFigure);
            app.CommandwindowPanel.Title = 'Command window';
            app.TowerPanel = uipanel(app.CommandwindowPanel);
            app.TowerPanel.Title = 'Tower Panel';
            app.TowerLoadPanel = uipanel(app.TowerPanel);
            app.LoadTowerFileButton = uibutton(app.TowerLoadPanel, 'push');
            app.LoadTowerFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTowerFileButtonPushed, true);
            app.LoadTowerFileButton.Text = 'Load Tower File';
            app.ExportTowerFileButton = uibutton(app.TowerLoadPanel, 'push');
            app.ExportTowerFileButton.Enable = 'off';
            app.ExportTowerFileButton.Text = 'Export Tower File';
            app.TowerFileStatus = uilamp(app.TowerLoadPanel);
            app.FilenotloadedLabel = uilabel(app.TowerLoadPanel);
            app.FilenotloadedLabel.Text = 'File not loaded';
            app.FileloadedLabel = uilabel(app.TowerLoadPanel);
            app.FileloadedLabel.Visible = 'off';
            app.FileloadedLabel.Text = 'File loaded!';
            app.BladePanel = uipanel(app.CommandwindowPanel);
            app.BladePanel.Title = 'Blade Panel';
            app.TowerLoadPanel_2 = uipanel(app.BladePanel);
            app.LoadBladeFile = uibutton(app.TowerLoadPanel_2, 'push');
            app.LoadBladeFile.ButtonPushedFcn = createCallbackFcn(app, @LoadBladeFileButtonPushed2, true);
            app.LoadBladeFile.Text = 'Load Blade File';
            app.ExportBladeFile = uibutton(app.TowerLoadPanel_2, 'push');
            app.ExportBladeFile.Enable = 'off';
            app.ExportBladeFile.Text = 'Export Blade File';
            app.BladeFileStatus = uilamp(app.TowerLoadPanel_2);
            app.FileNotLoadedBlade = uilabel(app.TowerLoadPanel_2);
            app.FileNotLoadedBlade.Text = 'File not loaded';
            app.FileLoadedBlade = uilabel(app.TowerLoadPanel_2);
            app.FileLoadedBlade.Visible = 'off';
            app.FileLoadedBlade.Text = 'File loaded!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end